classdef interface_operation_matricielle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interface                   matlab.ui.Figure
        Matrice1TextAreaLabel       matlab.ui.control.Label
        matrice1                    matlab.ui.control.TextArea
        Matrice2TextAreaLabel       matlab.ui.control.Label
        matrice2                    matlab.ui.control.TextArea
        RsultatmatlabTextAreaLabel  matlab.ui.control.Label
        resultat_ref                matlab.ui.control.TextArea
        RsultatFPGATextAreaLabel    matlab.ui.control.Label
        RsultatFPGATextArea         matlab.ui.control.TextArea
        operation_group             matlab.ui.container.ButtonGroup
        AdditionButton              matlab.ui.control.RadioButton
        SoustractionButton          matlab.ui.control.RadioButton
        MultiplicationlmentparlmentButton  matlab.ui.control.RadioButton
        MultiplicationmatricielleButton  matlab.ui.control.RadioButton
        Taillematrice1Label         matlab.ui.control.Label
        ligneEditFieldLabel         matlab.ui.control.Label
        mat1_ligne                  matlab.ui.control.NumericEditField
        ColonneEditFieldLabel       matlab.ui.control.Label
        mat1_col                    matlab.ui.control.NumericEditField
        Taillematrice2Label         matlab.ui.control.Label
        ligneEditField_2Label       matlab.ui.control.Label
        mat2_ligne                  matlab.ui.control.NumericEditField
        ColonneEditField_2Label     matlab.ui.control.Label
        mat2_col                    matlab.ui.control.NumericEditField
        CalculerButton              matlab.ui.control.Button
    end


    methods (Access = public)
    
        function [matrice, erreur] = extraire_matrice(app, texte_param, taille)
            s=1;
            i=1;
            j=1;
            matrice_int = 0;
            texte = '';
            erreur = 0;
            for s = 1:strlength(texte_param)
                   if(strcmp(texte_param(1,s), ',') == 1)
                       matrice_int(i,j) = uint16(str2num(texte));
                       j = j+1;
                       texte = '';
                   elseif(strcmp(texte_param(1,s), ';') == 1)
                       matrice_int(i,j) = uint16(str2num(texte));
                       j = 1;
                       i = i+1;
                       texte = '';
                   else
                       texte = strcat(texte,texte_param(1,s));
                   end
            end
            i = i-1;
            taille_matrice = size(matrice_int);
            if(taille_matrice(1,1) ~= taille(1,1) || taille_matrice(1,2) ~= taille(1,2))
                uialert(app.interface, 'Une erreur s''est produite!', 'Erreur');
                erreur = 1;
            end
            matrice = matrice_int;
        end
        
        function calculer_operation_ref(app, matrice1_param, matrice2_param, mode_param)
            if(mode_param == 0)
                resultat = matrice1_param+matrice2_param;
            elseif(mode_param == 1)
                resultat = matrice1_param-matrice2_param;
            elseif(mode_param == 2)
                resultat = matrice1_param.*matrice2_param;
            elseif(mode_param == 3)
                resultat = matrice1_param*matrice2_param;
            else
                resultat = 0;
            end
            
            taille_mat = size(resultat);
            texte_affichage = '';
            for i = 1:taille_mat(1,1)
                for j = 1:taille_mat(1,2)
                    if(j == taille_mat(1,2))
                        texte_affichage = strcat(texte_affichage, num2str(resultat(i,j)));
                    else
                        texte_affichage = strcat(texte_affichage, num2str(resultat(i,j)), ',');
                    end
                end
                texte_affichage = strcat(texte_affichage, ';');
            end
            
            set(app.resultat_ref, 'Value', texte_affichage);
        end
        
    end


    methods (Access = private)

        % Button pushed function: CalculerButton
        function calculer_matrice(app, event)
            selection = app.operation_group.SelectedObject;
            text = selection.Text;
            mode = uint8(0);
            if(strcmp(text, 'Addition') == 1)
                mode = uint8(0);
            elseif(strcmp(text, 'Soustraction') == 1)
                mode = uint8(1);
            elseif(strcmp(text, 'Multiplication élément par élément') == 1)
                mode = uint8(2);
            elseif(strcmp(text, 'Multiplication matricielle') == 1)
                mode = uint8(3);
            else
                mode = uint8(255);
                disp('Je ne devrais pas entrer là!');
            end
            
            taille_matrice1(1,1) = app.mat1_ligne.Value;
            taille_matrice1(1,2) = app.mat1_col.Value;
            taille_matrice2(1,1) = app.mat2_ligne.Value;
            taille_matrice2(1,2) = app.mat2_col.Value;
            
            texte_matrice1 = char(app.matrice1.Value);
            texte_matrice2 = char(app.matrice2.Value);
            [matrice1_int, erreur_int1] = extraire_matrice(app, texte_matrice1, taille_matrice1);
            [matrice2_int, erreur_int2] = extraire_matrice(app, texte_matrice2, taille_matrice2);
            
            if(erreur_int1 == 0 && erreur_int2 ==0)
            
                nb_element_mat1 = uint8(taille_matrice1(1,1)*taille_matrice1(1,2));
                nb_element_mat2 = uint8(taille_matrice2(1,1)*taille_matrice2(1,2));
                
                calculer_operation_ref(app, matrice1_int, matrice2_int, mode);
                
    %             port = serial('COM4', 'BaudRate', 921600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None',...
    %                     'ByteOrder', 'bigEndian', 'InputBufferSize', 2500);
    %             
    %             fopen(port);
    %             fwrite(port, mode, 'uint8');
    %             fwrite(port, nb_element_mat1, 'uint8');
    %             envoyer_matrice(app, port, matrice1_int);
    %             fwrite(port, nb_element_mat2, 'uint8');
    %             envoyer_matrice(app, port, matrice2_int);
    %             recuperer_data(app, port)
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interface = uifigure;
            app.interface.Name = 'Test opération matricielle';
            setAutoResize(app, app.interface, true)
            app.Matrice1TextAreaLabel = uilabel(app.interface);
            app.Matrice1TextAreaLabel.Text = 'Matrice 1';
            app.matrice1 = uitextarea(app.interface);
            app.Matrice2TextAreaLabel = uilabel(app.interface);
            app.Matrice2TextAreaLabel.Text = 'Matrice 2';
            app.matrice2 = uitextarea(app.interface);
            app.RsultatmatlabTextAreaLabel = uilabel(app.interface);
            app.RsultatmatlabTextAreaLabel.Text = 'Résultat matlab';
            app.resultat_ref = uitextarea(app.interface);
            app.RsultatFPGATextAreaLabel = uilabel(app.interface);
            app.RsultatFPGATextAreaLabel.Text = 'Résultat FPGA';
            app.RsultatFPGATextArea = uitextarea(app.interface);
            app.operation_group = uibuttongroup(app.interface);
            app.operation_group.Title = 'Opération';
            app.AdditionButton = uiradiobutton(app.operation_group);
            app.AdditionButton.Text = 'Addition';
            app.AdditionButton.Value = true;
            app.SoustractionButton = uiradiobutton(app.operation_group);
            app.SoustractionButton.Text = 'Soustraction';
            app.MultiplicationlmentparlmentButton = uiradiobutton(app.operation_group);
            app.MultiplicationlmentparlmentButton.Text = 'Multiplication élément par élément';
            app.MultiplicationmatricielleButton = uiradiobutton(app.operation_group);
            app.MultiplicationmatricielleButton.Text = 'Multiplication matricielle';
            app.Taillematrice1Label = uilabel(app.interface);
            app.Taillematrice1Label.Text = 'Taille matrice 1';
            app.ligneEditFieldLabel = uilabel(app.interface);
            app.ligneEditFieldLabel.Text = 'ligne';
            app.mat1_ligne = uieditfield(app.interface, 'numeric');
            app.mat1_ligne.Limits = [1 Inf];
            app.mat1_ligne.Value = 1;
            app.ColonneEditFieldLabel = uilabel(app.interface);
            app.ColonneEditFieldLabel.Text = 'Colonne';
            app.mat1_col = uieditfield(app.interface, 'numeric');
            app.mat1_col.Limits = [1 Inf];
            app.mat1_col.Value = 1;
            app.Taillematrice2Label = uilabel(app.interface);
            app.Taillematrice2Label.Text = 'Taille matrice 2';
            app.ligneEditField_2Label = uilabel(app.interface);
            app.ligneEditField_2Label.Text = 'ligne';
            app.mat2_ligne = uieditfield(app.interface, 'numeric');
            app.mat2_ligne.Limits = [1 Inf];
            app.mat2_ligne.Value = 1;
            app.ColonneEditField_2Label = uilabel(app.interface);
            app.ColonneEditField_2Label.Text = 'Colonne';
            app.mat2_col = uieditfield(app.interface, 'numeric');
            app.mat2_col.Limits = [1 Inf];
            app.mat2_col.Value = 1;
            app.CalculerButton = uibutton(app.interface, 'push');
            app.CalculerButton.ButtonPushedFcn = createCallbackFcn(app, @calculer_matrice, true);
            app.CalculerButton.Text = 'Calculer';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_operation_matricielle()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interface)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interface)
        end
    end
end