classdef interface_test_cyclovolt1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfacedetestUIFigure       matlab.ui.Figure
        Interfacedetestversion1Label  matlab.ui.control.Label
        graph                         matlab.ui.control.UIAxes
        btn_forme_onde                matlab.ui.container.ButtonGroup
        OndecarreButton               matlab.ui.control.RadioButton
        OndetriangulaireButton        matlab.ui.control.RadioButton
        OndesinusodaleButton          matlab.ui.control.RadioButton
        OndetriangulairepulseButton   matlab.ui.control.RadioButton
        ParamtresLabel                matlab.ui.control.Label
        FrquenceHzLabel               matlab.ui.control.Label
        AmplitudeVLabel               matlab.ui.control.Label
        OffsetVLabel                  matlab.ui.control.Label
        RapportcycliqueLabel          matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        frequence                     matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        amp                           matlab.ui.control.NumericEditField
        EditField_3Label              matlab.ui.control.Label
        offset_f                      matlab.ui.control.NumericEditField
        EditField_4Label              matlab.ui.control.Label
        rapport_cyclique              matlab.ui.control.NumericEditField
        NombredecyclesgnrsLabel       matlab.ui.control.Label
        EditField_5Label              matlab.ui.control.Label
        nombre_cycles                 matlab.ui.control.NumericEditField
        GnrerButton                   matlab.ui.control.Button
        ChoixhorlogepulseLabel        matlab.ui.control.Label
        choix_clk                     matlab.ui.control.Spinner
        Canal1                        matlab.ui.control.CheckBox
        Canal2                        matlab.ui.control.CheckBox
        Canal3                        matlab.ui.control.CheckBox
        Canal4                        matlab.ui.control.CheckBox
        Canal5                        matlab.ui.control.CheckBox
        Canal6                        matlab.ui.control.CheckBox
        Canal7                        matlab.ui.control.CheckBox
        Canal8                        matlab.ui.control.CheckBox
        ChoixdescanauxLabel           matlab.ui.control.Label
        label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        nb_conv_f                     matlab.ui.control.Spinner
        ConvertirButton               matlab.ui.control.Button
    end


    methods (Access = public)
    
        function res = gen_onde_carre(app)
            %calculer les données à envoye
            mode = uint8(1);
            nb_cycle = uint8(app.nombre_cycles.Value);
            nb_coup_clk_par_cycle = ceil(25000000/(app.frequence.Value));
            duty_cycle = uint32(nb_coup_clk_par_cycle*(app.rapport_cyclique.Value)/100);
            nb_coup_clk_par_cycle = uint32(nb_coup_clk_par_cycle);
            amplitude = uint16((app.amp.Value)*32767/4.6);
            offset = int16((app.offset_f.Value)*32767/4.6);
            
            %envoyer les données sur le port série
            port = serial('COM6', 'BaudRate', 9600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None', 'ByteOrder', 'bigEndian');
            fopen(port);
            fwrite(port, mode, 'uint8')
            fwrite(port, duty_cycle, 'uint32')
            fwrite(port, nb_coup_clk_par_cycle, 'uint32')
            fwrite(port,nb_cycle,'uint16')
            fwrite(port, amplitude, 'uint16')
            fwrite(port, offset, 'int16')
            fclose(port);
            delete(port);
            
        end
        
        function res = gen_onde_tri(app)
            %calculer les données à envoye
            mode = uint8(2);
            pas_test = (80*app.amp.Value*32767/4.6)/(25000000/(app.frequence.Value));
            pas = 0;
            temps_attente = 0;
            if(pas_test < 1)
                pas = uint16(1);
                temps_attente = uint32(ceil((25000000/(app.frequence.Value))/(4*app.amp.Value*32767/4.6))-20);
            else
                pas = uint16(ceil(pas_test));
                temps_attente = uint32(0);
            end
            amplitude = uint16(app.amp.Value*32767/4.6);
            offset = int16(app.offset_f.Value*32767/4.6);
            nb_cycle = uint8(app.nombre_cycles.Value);
            
            %envoyer les données sur le port série
            port = serial('COM6', 'BaudRate', 9600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None', 'ByteOrder', 'bigEndian');
            fopen(port);
            fwrite(port, mode, 'uint8')
            fwrite(port, pas, 'uint16')
            fwrite(port, temps_attente, 'int32')
            fwrite(port,nb_cycle,'uint16')
            fwrite(port, amplitude, 'uint16')
            fwrite(port, offset, 'uint16')
            fclose(port);
            delete(port); 
            
        end
        
        function res = gen_onde_sin(app)
            %calculer les données à envoyer
            mode = uint8(3);
            nb_cycle = uint8(app.nombre_cycles.Value);
            amplitude = uint16(app.amp.Value*1024);
            offset = int16(app.offset_f.Value*32767/4.6);
            fin = 0;
            temps_attente_test = 0;
            pas = 1;
            while(fin == 0)
                temps_attente_test = ((pas*25000000)/(2048*app.frequence.Value))-20;
                if(temps_attente_test < 1)
                    pas = pas*2;
                else
                    fin = 1;
                end
            end
            pas_envoie = uint8(pas);
            temps_attente = uint32(ceil(temps_attente_test));
            
            %envoyer les données sur le port série
            port = serial('COM6', 'BaudRate', 9600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None', 'ByteOrder', 'bigEndian');
            fopen(port);
            fwrite(port, mode, 'uint8')
            fwrite(port, temps_attente, 'uint32')
            fwrite(port, pas_envoie, 'uint8')
            fwrite(port,nb_cycle,'uint16')
            fwrite(port, amplitude, 'uint16')
            fwrite(port, offset, 'int16')
            fclose(port);
            delete(port); 
        end
        
        function res = gen_onde_tri_pulse(app)
             %calculer les données à envoye
            mode = uint8(4);
            pas_test = (80*app.amp.Value*32767/4.6)/(25000000/(app.frequence.Value));
            pas = 0;
            temps_attente = 0;
            if(pas_test < 1)
                pas = uint16(1);
                temps_attente = uint32(ceil((25000000/(app.frequence.Value))/(4*app.amp.Value*32767/4.6))-20);
            else
                pas = uint16(ceil(pas_test));
                temps_attente = uint32(0);
            end
            amplitude = uint16(app.amp.Value*32767/4.6);
            offset = int16(app.offset_f.Value*32767/4.6);
            nb_cycle = uint8(app.nombre_cycles.Value);
            choix_horloge = uint8(app.choix_clk.Value);
            
            %envoyer les données sur le port série
            port = serial('COM6', 'BaudRate', 9600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None', 'ByteOrder', 'bigEndian');
            fopen(port);
            fwrite(port, mode, 'uint8')
            fwrite(port, choix_horloge, 'uint8')
            fwrite(port, pas, 'uint16')
            fwrite(port, temps_attente, 'int32')
            fwrite(port,nb_cycle,'uint16')
            fwrite(port, amplitude, 'uint16')
            fwrite(port, offset, 'uint16')
            fclose(port);
            delete(port); 
        end
        
        function envoyer_1CH(app, mode, canal, nb_conv)
            mode_envoie = uint8(mode);
            canal_envoie = uint8(canal);
            nb_conv_envoie = uint32(nb_conv);
            
            %envoyer les données
            port = serial('COM6', 'BaudRate', 921600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None', 'ByteOrder', 'bigEndian');
%             port.BytesAvailableFcnCount = 16;
%             port.BytesAvailableFcnMode = 'byte';
%             port.BytesAvailableFcn = @instrcallback;
            
            fopen(port);
            fwrite(port, mode_envoie, 'uint8')
            fwrite(port, 0, 'uint8')
            fwrite(port, canal_envoie, 'uint8')
            fwrite(port, nb_conv_envoie, 'uint32')
            fin = 0 ;
            pause(2)
            port
            
            out = fread(port, port.BytesAvailable/2, 'uint16')
            length(out)
            %plot(app.graph, 1:64, out)
            fclose(port);
            delete(port);
        end
        
        function envoyer_seq(app, mode, sequence, nb_canaux, nb_conv)
            mode_envoie = uint8(mode);
            sequence_envoie = uint8(sequence);
            nb_canaux_envoie = uint8(nb_canaux);
            nb_conv_envoie = uint32(nb_conv);
            
            %envoyer les données
            port = serial('COM6', 'BaudRate', 9600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None', 'ByteOrder', 'bigEndian');
            fopen(port);
            fwrite(port, mode_envoie, 'uint8')
            fwrite(port, sequence_envoie, 'uint8')
            fwrite(port, nb_canaux_envoie, 'uint8')
            fwrite(port, nb_conv_envoie, 'uint32')
            fclose(port);
            delete(port);
        end
    end


    methods (Access = private)

        % Selection changed function: btn_forme_onde
        function changer_radio_btn(app, event)
            selection = app.btn_forme_onde.SelectedObject;
            text_selection = selection.Text;
            if(strcmp(text_selection, 'Onde carrée') == 1)
                set(app.rapport_cyclique, 'Enable', 'On');
            else
                set(app.rapport_cyclique, 'Enable', 'Off');
            end
            
            if(strcmp(text_selection, 'Onde triangulaire pulsée') == 1)
                set(app.choix_clk, 'Enable', 'On');
            else
                set(app.choix_clk, 'Enable', 'Off');
            end
             
        end

        % Button pushed function: GnrerButton
        function demarrer_gen(app, event)
            selection = app.btn_forme_onde.SelectedObject;
            text_selection = selection.Text;
            if(strcmp(text_selection, 'Onde carrée') == 1)
                gen_onde_carre(app);
            elseif(strcmp(text_selection, 'Onde triangulaire') == 1)
                    gen_onde_tri(app);
            elseif(strcmp(text_selection, 'Onde sinusoïdale') == 1)
                gen_onde_sin(app);
            elseif(strcmp(text_selection, 'Onde triangulaire pulsée') == 1)
                gen_onde_tri_pulse(app);
            else
                disp('je ne devrais pas aller la!!');
            end
            
        end

        % Button pushed function: ConvertirButton
        function convertir_CH(app, event)
            %calculer la séquence
            nb_canaux = 0;
            mode = uint(0);
            sequence = uint8(0);
            canaux(1,1) = app.Canal1;
            canaux(1,2) = app.Canal2;
            canaux(1,3) = app.Canal3;
            canaux(1,4) = app.Canal4;
            canaux(1,5) = app.Canal5;
            canaux(1,6) = app.Canal6;
            canaux(1,7) = app.Canal7;
            canaux(1,8) = app.Canal8;
            mult = uint8(128);
            canal = 0;
            for i = 1:8
                if(canaux(1,i).Value == 1)
                    nb_canaux = nb_canaux + 1;
                    canal = i-1;
                    sequence = bitor(sequence, mult, 'uint8');
                end
                mult = mult/2;
            end
               
            
            %détermine quel mode choisir
            if(nb_canaux == 1)
                mode = uint8(1);
                envoyer_1CH(app, mode, canal, app.nb_conv_f.Value);
            elseif(nb_canaux > 1)
                mode = uint8(2);
                envoyer_seq(app, mode, sequence, nb_canaux, app.nb_conv_f.Value);
            else
                mode = uint8(0);
                uialert(app.InterfacedetestUIFigure, 'Le nombre de canaux est insuffisant.', 'Erreur');
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfacedetestUIFigure = uifigure;
            app.InterfacedetestUIFigure.Name = 'Interface de test';
            setAutoResize(app, app.InterfacedetestUIFigure, true)
            app.Interfacedetestversion1Label = uilabel(app.InterfacedetestUIFigure);
            app.Interfacedetestversion1Label.Text = 'Interface de test (version 1)';
            app.graph = uiaxes(app.InterfacedetestUIFigure);
            title(app.graph, 'Courant (A) en fonction de la tension (V)');
            xlabel(app.graph, 'Tension (V)');
            ylabel(app.graph, 'Courant(A)');
            app.btn_forme_onde = uibuttongroup(app.InterfacedetestUIFigure);
            app.btn_forme_onde.SelectionChangedFcn = createCallbackFcn(app, @changer_radio_btn, true);
            app.btn_forme_onde.Title = 'Formes d''onde';
            app.OndecarreButton = uiradiobutton(app.btn_forme_onde);
            app.OndecarreButton.Text = 'Onde carrée';
            app.OndecarreButton.Value = true;
            app.OndetriangulaireButton = uiradiobutton(app.btn_forme_onde);
            app.OndetriangulaireButton.Text = 'Onde triangulaire';
            app.OndesinusodaleButton = uiradiobutton(app.btn_forme_onde);
            app.OndesinusodaleButton.Text = 'Onde sinusoïdale';
            app.OndetriangulairepulseButton = uiradiobutton(app.btn_forme_onde);
            app.OndetriangulairepulseButton.Text = 'Onde triangulaire pulsée';
            app.ParamtresLabel = uilabel(app.InterfacedetestUIFigure);
            app.ParamtresLabel.Text = 'Paramètres';
            app.FrquenceHzLabel = uilabel(app.InterfacedetestUIFigure);
            app.FrquenceHzLabel.Text = 'Fréquence (Hz)';
            app.AmplitudeVLabel = uilabel(app.InterfacedetestUIFigure);
            app.AmplitudeVLabel.Text = 'Amplitude (V)';
            app.OffsetVLabel = uilabel(app.InterfacedetestUIFigure);
            app.OffsetVLabel.Text = 'Offset (V)';
            app.RapportcycliqueLabel = uilabel(app.InterfacedetestUIFigure);
            app.RapportcycliqueLabel.Text = 'Rapport cyclique (%)';
            app.Label = uilabel(app.InterfacedetestUIFigure);
            app.Label.Text = '';
            app.frequence = uieditfield(app.InterfacedetestUIFigure, 'numeric');
            app.frequence.Limits = [0 10000];
            app.frequence.ValueDisplayFormat = '%.2f';
            app.frequence.Value = 1000;
            app.EditField_2Label = uilabel(app.InterfacedetestUIFigure);
            app.EditField_2Label.Text = '';
            app.amp = uieditfield(app.InterfacedetestUIFigure, 'numeric');
            app.amp.Limits = [0 2.5];
            app.amp.ValueDisplayFormat = '%.2f';
            app.amp.Value = 1;
            app.EditField_3Label = uilabel(app.InterfacedetestUIFigure);
            app.EditField_3Label.Text = '';
            app.offset_f = uieditfield(app.InterfacedetestUIFigure, 'numeric');
            app.offset_f.Limits = [-2 2];
            app.offset_f.ValueDisplayFormat = '%.2f';
            app.EditField_4Label = uilabel(app.InterfacedetestUIFigure);
            app.EditField_4Label.Text = '';
            app.rapport_cyclique = uieditfield(app.InterfacedetestUIFigure, 'numeric');
            app.rapport_cyclique.Limits = [10 90];
            app.rapport_cyclique.ValueDisplayFormat = '%.0f';
            app.rapport_cyclique.Value = 50;
            app.NombredecyclesgnrsLabel = uilabel(app.InterfacedetestUIFigure);
            app.NombredecyclesgnrsLabel.Text = 'Nombre de cycles générés';
            app.EditField_5Label = uilabel(app.InterfacedetestUIFigure);
            app.EditField_5Label.Text = '';
            app.nombre_cycles = uieditfield(app.InterfacedetestUIFigure, 'numeric');
            app.nombre_cycles.Limits = [1 65535];
            app.nombre_cycles.ValueDisplayFormat = '%.0f';
            app.nombre_cycles.Value = 100;
            app.GnrerButton = uibutton(app.InterfacedetestUIFigure, 'push');
            app.GnrerButton.ButtonPushedFcn = createCallbackFcn(app, @demarrer_gen, true);
            app.GnrerButton.Text = 'Générer';
            app.ChoixhorlogepulseLabel = uilabel(app.InterfacedetestUIFigure);
            app.ChoixhorlogepulseLabel.Text = 'Choix horloge pulse';
            app.choix_clk = uispinner(app.InterfacedetestUIFigure);
            app.choix_clk.Limits = [0 15];
            app.choix_clk.Enable = 'off';
            app.Canal1 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal1.Text = 'Canal 1';
            app.Canal1.Value = true;
            app.Canal2 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal2.Text = 'Canal 2';
            app.Canal3 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal3.Text = 'Canal 3';
            app.Canal4 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal4.Text = 'Canal 4';
            app.Canal5 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal5.Text = 'Canal 5';
            app.Canal6 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal6.Text = 'Canal 6';
            app.Canal7 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal7.Text = 'Canal 7';
            app.Canal8 = uicheckbox(app.InterfacedetestUIFigure);
            app.Canal8.Text = 'Canal 8';
            app.ChoixdescanauxLabel = uilabel(app.InterfacedetestUIFigure);
            app.ChoixdescanauxLabel.Text = 'Choix des canaux';
            app.label = uilabel(app.InterfacedetestUIFigure);
            app.label.Text = 'Nombre de conversions';
            app.Label_2 = uilabel(app.InterfacedetestUIFigure);
            app.Label_2.Text = '';
            app.nb_conv_f = uispinner(app.InterfacedetestUIFigure);
            app.nb_conv_f.Limits = [1 4294967295];
            app.nb_conv_f.Value = 1;
            app.ConvertirButton = uibutton(app.InterfacedetestUIFigure, 'push');
            app.ConvertirButton.ButtonPushedFcn = createCallbackFcn(app, @convertir_CH, true);
            app.ConvertirButton.Text = 'Convertir';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_test_cyclovolt1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfacedetestUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfacedetestUIFigure)
        end
    end
end