classdef interface_test_cyclovolt2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interface                     matlab.ui.Figure
        Interfacedetestversion2Label  matlab.ui.control.Label
        graph                         matlab.ui.control.UIAxes
        btn_forme_onde                matlab.ui.container.ButtonGroup
        OndecarreButton               matlab.ui.control.RadioButton
        OndetriangulaireButton        matlab.ui.control.RadioButton
        OndesinusodaleButton          matlab.ui.control.RadioButton
        OndetriangulairepulseButton   matlab.ui.control.RadioButton
        ParamtresLabel                matlab.ui.control.Label
        FrquenceHzLabel               matlab.ui.control.Label
        AmplitudeVLabel               matlab.ui.control.Label
        OffsetVLabel                  matlab.ui.control.Label
        RapportcycliqueLabel          matlab.ui.control.Label
        ChoixhorlogepulseLabel        matlab.ui.control.Label
        choix_clk                     matlab.ui.control.Spinner
        Canal1                        matlab.ui.control.CheckBox
        Canal2                        matlab.ui.control.CheckBox
        Canal3                        matlab.ui.control.CheckBox
        Canal4                        matlab.ui.control.CheckBox
        Canal5                        matlab.ui.control.CheckBox
        Canal6                        matlab.ui.control.CheckBox
        Canal7                        matlab.ui.control.CheckBox
        Canal8                        matlab.ui.control.CheckBox
        ChoixdescanauxLabel           matlab.ui.control.Label
        DmarrerButton                 matlab.ui.control.Button
        frequenceLabel                matlab.ui.control.Label
        frequence                     matlab.ui.control.NumericEditField
        ampLabel                      matlab.ui.control.Label
        amp                           matlab.ui.control.NumericEditField
        offset_fLabel                 matlab.ui.control.Label
        offset_f                      matlab.ui.control.NumericEditField
        rapport_cycliqueLabel         matlab.ui.control.Label
        rapport_cyclique              matlab.ui.control.NumericEditField
        rapport_cyclique_2Label       matlab.ui.control.Label
        ts                            matlab.ui.control.NumericEditField
        TempsdchantillonnagesLabel    matlab.ui.control.Label
        EffacerButton                 matlab.ui.control.Button
        ZoomXSliderLabel              matlab.ui.control.Label
        ZoomXSlider                   matlab.ui.control.Slider
        ZoomYSliderLabel              matlab.ui.control.Label
        ZoomYSlider                   matlab.ui.control.Slider
        SauvegarderButton             matlab.ui.control.Button
        nom_sauvegarde                matlab.ui.control.EditField
        OuvrirButton                  matlab.ui.control.Button
        nom_fichier_open              matlab.ui.control.EditField
        label                         matlab.ui.control.Label
        NomdufichierLabel             matlab.ui.control.Label
        choix_affichage               matlab.ui.container.ButtonGroup
        OuiButton                     matlab.ui.control.RadioButton
        NonButton                     matlab.ui.control.RadioButton
        GainSpinnerLabel              matlab.ui.control.Label
        gain                          matlab.ui.control.Spinner
        FiltremoyenneurSpinnerLabel   matlab.ui.control.Label
        choix_oversampling            matlab.ui.control.Spinner
    end

    
    methods (Access = public)
        function res = gen_onde_carre(app)
            %calculer les données à envoye
            mode = uint8(1);
            nb_cycle = uint32(app.ts.Value*app.frequence.Value);
            nb_coup_clk_par_cycle = ceil(25000000/(app.frequence.Value));
            duty_cycle = uint32(nb_coup_clk_par_cycle*(app.rapport_cyclique.Value)/100);
            nb_coup_clk_par_cycle = uint32(nb_coup_clk_par_cycle);
            amplitude = uint16((app.amp.Value)*32767/4.6);
            offset = int16((app.offset_f.Value)*32767/4.6);
            
            [nb_canaux_envoie,canal_envoie,sequence_envoie, nb_conv_12, nb_conv_10] = convertir_CH(app);
            
            if(nb_canaux_envoie == 0)
                uialert(app.interface, 'Le nombre de canaux est insuffisant.', 'Erreur');
            else
                %envoyer les données sur le port série
                port = serial('COM4', 'BaudRate', 921600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None',...
                    'ByteOrder', 'bigEndian', 'InputBufferSize', 790000);
                
                fopen(port);
                fwrite(port, mode, 'uint8')
                fwrite(port, duty_cycle, 'uint32')
                fwrite(port, nb_coup_clk_par_cycle, 'uint32')
                fwrite(port,nb_cycle,'uint32')
                fwrite(port, amplitude, 'uint16')
                fwrite(port, offset, 'int16')
                gain_val = uint8(app.gain.Value);
                fwrite(port, gain_val, 'uint8')
                if(nb_canaux_envoie == 1)
                    mode_envoie = uint8(1);
                    envoyer_1CH(app, canal_envoie, mode_envoie, nb_conv_12, nb_conv_10, port);
                else
                    mode_envoie = uint8(2);
                    envoyer_seq(app, mode_envoie, sequence_envoie, nb_conv_12, nb_conv_10, port);
                end
                fclose(port);
                delete(port);
            end
            
        end
        
        function res = gen_onde_tri(app)
            %calculer les données à envoyer
            mode = uint8(2);
            pas_test = (80*app.amp.Value*32767/4.6)/(25000000/(app.frequence.Value));
            pas = 0;
            temps_attente = 0;
            if(pas_test < 1)
                pas = uint16(1);
                temps_attente = uint32(ceil((25000000/(app.frequence.Value))/(4*app.amp.Value*32767/4.6))-20);
            else
                pas = uint16(ceil(pas_test));
                temps_attente = uint32(0);
            end
            amplitude = uint16(app.amp.Value*32767/4.6);
            offset = int16(app.offset_f.Value*32767/4.6);
            nb_cycle = uint32(app.ts.Value*app.frequence.Value);
            
            [nb_canaux_envoie,canal_envoie,sequence_envoie, nb_conv_12, nb_conv_10] = convertir_CH(app);
            
            if(nb_canaux_envoie == 0)
                uialert(app.interface, 'Le nombre de canaux est insuffisant.', 'Erreur');
            else
                %envoyer les données sur le port série
                port = serial('COM4', 'BaudRate', 921600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None',...
                    'ByteOrder', 'bigEndian', 'InputBufferSize', 790000);
                fopen(port);
                fwrite(port, mode, 'uint8')
                fwrite(port, pas, 'uint16')
                fwrite(port, temps_attente, 'int32')
                fwrite(port,nb_cycle,'uint32')
                fwrite(port, amplitude, 'uint16')
                fwrite(port, offset, 'int16')
                gain_val = uint8(app.gain.Value);
                fwrite(port, gain_val, 'uint8')
                if(nb_canaux_envoie == 1)
                    mode_envoie = uint8(1);
                    envoyer_1CH(app, canal_envoie, mode_envoie, nb_conv_12, nb_conv_10, port);
                else
                    mode_envoie = uint8(2);
                    envoyer_seq(app, mode_envoie, sequence_envoie, nb_conv_12, nb_conv_10, port);
                end
                fclose(port);
                delete(port);
            end
        end
        
        function res = gen_onde_sin(app)
            %calculer les données à envoyer
            mode = uint8(3);
            nb_cycle = uint32(app.ts.Value*app.frequence.Value);
            amplitude = uint16(app.amp.Value*1024);
            offset = int16(app.offset_f.Value*32767/4.6);
            fin = 0;
            temps_attente_test = 0;
            pas = 1;
            while(fin == 0)
                temps_attente_test = ((pas*25000000)/(2048*app.frequence.Value))-20;
                if(temps_attente_test < 1)
                    pas = pas*2;
                else
                    fin = 1;
                end
            end
            pas_envoie = uint8(pas);
            temps_attente = uint32(ceil(temps_attente_test));
            
            [nb_canaux_envoie,canal_envoie,sequence_envoie, nb_conv_12, nb_conv_10] = convertir_CH(app);
            
            if(nb_canaux_envoie == 0)
                uialert(app.interface, 'Le nombre de canaux est insuffisant.', 'Erreur');
            else
                %envoyer les données sur le port série
                port = serial('COM4', 'BaudRate', 921600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None',...
                    'ByteOrder', 'bigEndian', 'InputBufferSize', 790000);
                fopen(port);
                fwrite(port, mode, 'uint8')
                fwrite(port, temps_attente, 'uint32')
                fwrite(port, pas_envoie, 'uint8')
                fwrite(port,nb_cycle,'uint32')
                fwrite(port, amplitude, 'uint16')
                fwrite(port, offset, 'int16')
                gain_val = uint8(app.gain.Value);
                fwrite(port, gain_val, 'uint8')
                
                if(nb_canaux_envoie == 1)
                    mode_envoie = uint8(1);
                    envoyer_1CH(app, canal_envoie, mode_envoie, nb_conv_12, nb_conv_10, port);
                else
                    mode_envoie = uint8(2);
                    envoyer_seq(app, mode_envoie, sequence_envoie, nb_conv_12, nb_conv_10, port);
                end
                fclose(port);
                delete(port);
            end
        end
        
        function res = gen_onde_tri_pulse(app)
            %calculer les données à envoye
            mode = uint8(4);
            pas_test = (80*app.amp.Value*32767/4.6)/(25000000/(app.frequence.Value));
            pas = 0;
            temps_attente = 0;
            if(pas_test < 1)
                pas = uint16(1);
                temps_attente = uint32(ceil((25000000/(app.frequence.Value))/(4*app.amp.Value*32767/4.6))-20);
            else
                pas = uint16(ceil(pas_test));
                temps_attente = uint32(0);
            end
            amplitude = uint16(app.amp.Value*32767/4.6);
            offset = int16(app.offset_f.Value*32767/4.6);
            nb_cycle = uint32(app.ts.Value*app.frequence.Value);
            choix_horloge = uint8(app.choix_clk.Value);
            
            [nb_canaux_envoie,canal_envoie,sequence_envoie, nb_conv_12, nb_conv_10] = convertir_CH(app);
            
            if(nb_canaux_envoie == 0)
                uialert(app.interface, 'Le nombre de canaux est insuffisant.', 'Erreur');
            else
                %envoyer les données sur le port série
                port = serial('COM4', 'BaudRate', 921600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None',...
                    'ByteOrder', 'bigEndian', 'InputBufferSize', 790000);
                fopen(port);
                fwrite(port, mode, 'uint8')
                fwrite(port, choix_horloge, 'uint8')
                fwrite(port, pas, 'uint16')
                fwrite(port, temps_attente, 'int32')
                fwrite(port,nb_cycle,'uint32')
                fwrite(port, amplitude, 'uint16')
                fwrite(port, offset, 'int16')
                gain_val = uint8(app.gain.Value);
                fwrite(port, gain_val, 'uint8')
                if(nb_canaux_envoie == 1)
                    mode_envoie = uint8(1);
                    envoyer_1CH(app, canal_envoie, mode_envoie, nb_conv_12, nb_conv_10, port);
                else
                    mode_envoie = uint8(2);
                    envoyer_seq(app, mode_envoie, sequence_envoie, nb_conv_12, nb_conv_10, port);
                end
                fclose(port);
                delete(port);
            end
        end
        
        function [nb_canaux_envoie,canal_envoie,sequence_envoie, nb_conv_12, nb_conv_10] = convertir_CH(app)
            %calculer la séquence
            nb_canaux = 0;
            sequence = uint8(0);
            canaux(1,1) = app.Canal1;
            canaux(1,2) = app.Canal2;
            canaux(1,3) = app.Canal3;
            canaux(1,4) = app.Canal4;
            canaux(1,5) = app.Canal5;
            canaux(1,6) = app.Canal6;
            canaux(1,7) = app.Canal7;
            canaux(1,8) = app.Canal8;
            mult = uint8(128);
            canal = 0;
            for i = 1:8
                if(canaux(1,i).Value == 1)
                    nb_canaux = nb_canaux + 1;
                    canal = i-1;
                    sequence = bitor(sequence, mult, 'uint8');
                end
                mult = mult/2;
            end
            
            nb_canaux_envoie = uint8(nb_canaux);
            canal_envoie = uint8(canal);
            sequence_envoie = uint8(sequence);
            nb_conv_12 = uint32(ceil(app.ts.Value*(12500000/19)));
            nb_conv_10 = uint32(ceil(app.ts.Value*(781250/36)));
        end
        
        function envoyer_1CH(app, canal,mode, nb_conv_12, nb_conv_10, port)
            mode_envoie = uint8(mode);
            canal_envoie = uint8(canal);
            nb_conv_envoie_12 = uint32(nb_conv_12);
            nb_conv_envoie_10 = uint32(nb_conv_10);
            choix_over = uint8(app.choix_oversampling.Value);
            
            fwrite(port, mode_envoie, 'uint8')
            fwrite(port, choix_over, 'uint8')
            fwrite(port, canal_envoie, 'uint8')
            fwrite(port, nb_conv_envoie_12, 'uint32')
            fwrite(port, nb_conv_envoie_10, 'uint32')
            recuperer_data(app, port);
        end
        
        function envoyer_seq(app, mode, sequence, nb_conv_12, nb_conv_10, port)
            mode_envoie = uint8(mode);
            sequence_envoie = uint8(sequence);
            nb_conv_12_envoie = uint32(nb_conv_12);
            nb_conv_10_envoie = uint32(nb_conv_10);
            choix_over = uint8(app.choix_oversampling.Value);
            
            %envoyer les données
            fwrite(port, mode_envoie, 'uint8')
            fwrite(port, choix_over, 'uint8')
            fwrite(port, sequence_envoie, 'uint8')
            fwrite(port, nb_conv_12_envoie, 'uint32')
            fwrite(port, nb_conv_10_envoie, 'uint32')
            recuperer_data(app, port);
        end
        
        function recuperer_data(app, port)
            %trouver le moyen de lire toutes les données
            pause(1)
            set(app.graph, 'NextPlot', 'add');
            app.graph.XLim = [-(app.amp.Value + 1) app.amp.Value + 1];
            app.graph.YLim = [-(app.amp.Value  + 1)/1000 (app.amp.Value  + 1)/1000];
            compte=0;
            selection = app.choix_affichage.SelectedObject;
            text_selection = selection.Text;
            
            matriceX = [];
            matriceY = [];
            while(port.BytesAvailable >= 100)
                data = fread(port, 50, 'int16');
                data_courant = [];
                data_tension = [];
                %récupérer le CH lorsque l'on va utiliser plusieurs canaux!
                for i=1:50
                    if(mod(i,2) == 0)
                        data_tension(i/2,1) = data(i,1);
                    else
                        data_courant((i+1)/2, 1) = data(i,1);
                    end
                end
                data_plot_tension = bitshift(data_tension, -5, 'int16');
                data_plot_courant_int = bitshift(data_courant, 3, 'int16');
                data_plot_courant = bitshift(data_plot_courant_int, -3, 'int16');
                resistance = (-807.68*app.gain.Value)+209529;
                gain_courant = (1+(resistance/(209529-resistance)))*500;
                data_courant_norm = (data_plot_courant/4096)*2.5/gain_courant;
                data_tension_norm = (data_plot_tension/1024)*2.048;
          
                %afficher en temps réeel
                if(strcmp(text_selection, 'Oui') == 1)
                    if(compte == 0)
                        plot(app.graph, data_tension_norm, data_courant_norm, 'b');
                    else
                        axe = get(app.graph, 'children');
                        xdata = get(axe, 'XData');
                        ydata = get(axe, 'YData');
                        matriceX = [xdata data_tension_norm'];
                        matriceY = [ydata data_courant_norm'];
                        set(axe, 'XData', matriceX);
                        set(axe, 'YData', matriceY);
                    end
                    compte = compte + 1;
                    pause(0.1);
                else
                    matriceX = [matriceX data_tension_norm'];
                    matriceY = [matriceY data_courant_norm'];
                    pause(0.002*2*app.choix_oversampling.Value);
                end
                
            end
            
            %afficher toutes le données pas en temps réel
            if(strcmp(text_selection, 'Non') == 1)
                plot(app.graph, matriceX, matriceY, 'b');
            end
            
            set(app.DmarrerButton, 'Enable', 'on');
            set(app.EffacerButton, 'Enable', 'on');
            set(app.OuvrirButton, 'Enable', 'on');
            set(app.SauvegarderButton, 'Enable', 'on');
        end
        
    end
    

    methods (Access = private)

        % Selection changed function: btn_forme_onde
        function changer_radion_btn(app, event)
            selection = app.btn_forme_onde.SelectedObject;
            text_selection = selection.Text;
            if(strcmp(text_selection, 'Onde carrée') == 1)
                set(app.rapport_cyclique, 'Enable', 'On');
            else
                set(app.rapport_cyclique, 'Enable', 'Off');
            end
            
            if(strcmp(text_selection, 'Onde triangulaire pulsée') == 1)
                set(app.choix_clk, 'Enable', 'On');
            else
                set(app.choix_clk, 'Enable', 'Off');
            end
            
        end

        % Button pushed function: DmarrerButton
        function demarrer(app, event)
            selection = app.btn_forme_onde.SelectedObject;
            text_selection = selection.Text;
            set(app.DmarrerButton, 'Enable', 'off');
            set(app.EffacerButton, 'Enable', 'off');
            set(app.OuvrirButton, 'Enable', 'off');
            set(app.SauvegarderButton, 'Enable', 'off');
            cla(app.graph);
            if(strcmp(text_selection, 'Onde carrée') == 1)
                gen_onde_carre(app);
            elseif(strcmp(text_selection, 'Onde triangulaire') == 1)
                gen_onde_tri(app);
            elseif(strcmp(text_selection, 'Onde sinusoïdale') == 1)
                gen_onde_sin(app);
            elseif(strcmp(text_selection, 'Onde triangulaire pulsée') == 1)
                gen_onde_tri_pulse(app);
            else
                disp('je ne devrais pas aller la!!');
            end
        end

        % Button pushed function: EffacerButton
        function effacer_plot(app, event)
            cla(app.graph)
        end

        % Value changed function: ZoomXSlider
        function zoomx(app, event)
            value = app.ZoomXSlider.Value;
            app.graph.XLim = [(-(app.amp.Value + 1))/(value/10) (app.amp.Value + 1)/(value/10)];
        end

        % Value changed function: ZoomYSlider
        function zoomy(app, event)
            value = app.ZoomYSlider.Value;
            app.graph.YLim = [(-(app.amp.Value + 1)/1000)/(value) ((app.amp.Value + 1)/1000)/(value)];
        end

        % Button pushed function: SauvegarderButton
        function sauvegarder_courbe(app, event)
            axe = get(app.graph, 'Children');
            type = get(axe, 'Type');
            [range, colonne] = size(axe);
            if(isempty(type) == 1)
                uialert(app.interface, 'Il n''y a pas de courbe à sauvegarder.', 'Erreur');
            else
                matrice_save = [];
                for i = 1:range
                    xdata = axe(i,1).XData; %tension
                    ydata = axe(i,1).YData; %courant
                    matrice_save = [matrice_save ; ydata' xdata'];
                end
                nom = strcat(app.nom_sauvegarde.Value,'.dat');
                dlmwrite(nom, matrice_save, 'precision', '%.8f');
            end
        end

        % Button pushed function: OuvrirButton
        function ouvrir_courbe(app, event)
            nom_fichier = app.nom_fichier_open.Value;
            cla(app.graph)
            try
                courbe = csvread(nom_fichier);
                plot(courbe(:,2), courbe(:,1), 'b');
            catch
                uialert(app.interface, 'Une erreur s''est produite lors de l''ouverture du fichier', 'Erreur');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interface = uifigure;
            app.interface.Name = 'Interface de test (version 2)';
            setAutoResize(app, app.interface, true)
            app.Interfacedetestversion2Label = uilabel(app.interface);
            app.Interfacedetestversion2Label.Text = 'Interface de test (version 2)';
            app.graph = uiaxes(app.interface);
            title(app.graph, 'Courant (A) en fonction de la tension (V)');
            xlabel(app.graph, 'Tension (V)');
            ylabel(app.graph, 'Courant(A)');
            app.btn_forme_onde = uibuttongroup(app.interface);
            app.btn_forme_onde.SelectionChangedFcn = createCallbackFcn(app, @changer_radion_btn, true);
            app.btn_forme_onde.Title = 'Formes d''onde';
            app.OndecarreButton = uiradiobutton(app.btn_forme_onde);
            app.OndecarreButton.Text = 'Onde carrée';
            app.OndecarreButton.Value = true;
            app.OndetriangulaireButton = uiradiobutton(app.btn_forme_onde);
            app.OndetriangulaireButton.Text = 'Onde triangulaire';
            app.OndesinusodaleButton = uiradiobutton(app.btn_forme_onde);
            app.OndesinusodaleButton.Text = 'Onde sinusoïdale';
            app.OndetriangulairepulseButton = uiradiobutton(app.btn_forme_onde);
            app.OndetriangulairepulseButton.Text = 'Onde triangulaire pulsée';
            app.ParamtresLabel = uilabel(app.interface);
            app.ParamtresLabel.Text = 'Paramètres';
            app.FrquenceHzLabel = uilabel(app.interface);
            app.FrquenceHzLabel.Text = 'Fréquence (Hz)';
            app.AmplitudeVLabel = uilabel(app.interface);
            app.AmplitudeVLabel.Text = 'Amplitude (V)';
            app.OffsetVLabel = uilabel(app.interface);
            app.OffsetVLabel.Text = 'Offset (V)';
            app.RapportcycliqueLabel = uilabel(app.interface);
            app.RapportcycliqueLabel.Text = 'Rapport cyclique (%)';
            app.ChoixhorlogepulseLabel = uilabel(app.interface);
            app.ChoixhorlogepulseLabel.Text = 'Choix horloge pulse';
            app.choix_clk = uispinner(app.interface);
            app.choix_clk.Limits = [0 15];
            app.choix_clk.Enable = 'off';
            app.Canal1 = uicheckbox(app.interface);
            app.Canal1.Text = 'Canal 1';
            app.Canal1.Value = true;
            app.Canal2 = uicheckbox(app.interface);
            app.Canal2.Text = 'Canal 2';
            app.Canal3 = uicheckbox(app.interface);
            app.Canal3.Text = 'Canal 3';
            app.Canal4 = uicheckbox(app.interface);
            app.Canal4.Text = 'Canal 4';
            app.Canal5 = uicheckbox(app.interface);
            app.Canal5.Text = 'Canal 5';
            app.Canal6 = uicheckbox(app.interface);
            app.Canal6.Text = 'Canal 6';
            app.Canal7 = uicheckbox(app.interface);
            app.Canal7.Text = 'Canal 7';
            app.Canal8 = uicheckbox(app.interface);
            app.Canal8.Text = 'Canal 8';
            app.ChoixdescanauxLabel = uilabel(app.interface);
            app.ChoixdescanauxLabel.Text = 'Choix des canaux';
            app.DmarrerButton = uibutton(app.interface, 'push');
            app.DmarrerButton.ButtonPushedFcn = createCallbackFcn(app, @demarrer, true);
            app.DmarrerButton.Text = 'Démarrer';
            app.frequenceLabel = uilabel(app.interface);
            app.frequenceLabel.Text = '';
            app.frequence = uieditfield(app.interface, 'numeric');
            app.frequence.Limits = [0 10000];
            app.frequence.ValueDisplayFormat = '%.2f';
            app.frequence.Value = 1000;
            app.ampLabel = uilabel(app.interface);
            app.ampLabel.Text = '';
            app.amp = uieditfield(app.interface, 'numeric');
            app.amp.Limits = [0 2.5];
            app.amp.ValueDisplayFormat = '%.2f';
            app.amp.Value = 1;
            app.offset_fLabel = uilabel(app.interface);
            app.offset_fLabel.Text = '';
            app.offset_f = uieditfield(app.interface, 'numeric');
            app.offset_f.Limits = [-2 2];
            app.offset_f.ValueDisplayFormat = '%.2f';
            app.rapport_cycliqueLabel = uilabel(app.interface);
            app.rapport_cycliqueLabel.Text = '';
            app.rapport_cyclique = uieditfield(app.interface, 'numeric');
            app.rapport_cyclique.Limits = [10 90];
            app.rapport_cyclique.ValueDisplayFormat = '%.0f';
            app.rapport_cyclique.Value = 50;
            app.rapport_cyclique_2Label = uilabel(app.interface);
            app.rapport_cyclique_2Label.Text = '';
            app.ts = uieditfield(app.interface, 'numeric');
            app.ts.Limits = [1 60];
            app.ts.ValueDisplayFormat = '%.0f';
            app.ts.Value = 1;
            app.TempsdchantillonnagesLabel = uilabel(app.interface);
            app.TempsdchantillonnagesLabel.Text = 'Temps d''échantillonnage (s)';
            app.EffacerButton = uibutton(app.interface, 'push');
            app.EffacerButton.ButtonPushedFcn = createCallbackFcn(app, @effacer_plot, true);
            app.EffacerButton.Text = 'Effacer';
            app.ZoomXSliderLabel = uilabel(app.interface);
            app.ZoomXSliderLabel.Text = 'Zoom X';
            app.ZoomXSlider = uislider(app.interface);
            app.ZoomXSlider.Limits = [1 100];
            app.ZoomXSlider.ValueChangedFcn = createCallbackFcn(app, @zoomx, true);
            app.ZoomXSlider.Value = 10;
            app.ZoomYSliderLabel = uilabel(app.interface);
            app.ZoomYSliderLabel.Text = 'Zoom Y';
            app.ZoomYSlider = uislider(app.interface);
            app.ZoomYSlider.Limits = [1 100];
            app.ZoomYSlider.ValueChangedFcn = createCallbackFcn(app, @zoomy, true);
            app.ZoomYSlider.Value = 10;
            app.SauvegarderButton = uibutton(app.interface, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @sauvegarder_courbe, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.nom_sauvegarde = uieditfield(app.interface, 'text');
            app.nom_sauvegarde.Value = 'courbe1';
            app.OuvrirButton = uibutton(app.interface, 'push');
            app.OuvrirButton.ButtonPushedFcn = createCallbackFcn(app, @ouvrir_courbe, true);
            app.OuvrirButton.Text = 'Ouvrir';
            app.nom_fichier_open = uieditfield(app.interface, 'text');
            app.label = uilabel(app.interface);
            app.label.Text = 'Nom de la sauvegarde';
            app.NomdufichierLabel = uilabel(app.interface);
            app.NomdufichierLabel.Text = 'Nom du fichier ';
            app.choix_affichage = uibuttongroup(app.interface);
            app.choix_affichage.Title = 'Affichage temps réel';
            app.OuiButton = uiradiobutton(app.choix_affichage);
            app.OuiButton.Text = 'Oui';
            app.OuiButton.Value = true;
            app.NonButton = uiradiobutton(app.choix_affichage);
            app.NonButton.Text = 'Non';
            app.GainSpinnerLabel = uilabel(app.interface);
            app.GainSpinnerLabel.Text = 'Gain';
            app.gain = uispinner(app.interface);
            app.gain.Limits = [0 255];
            app.FiltremoyenneurSpinnerLabel = uilabel(app.interface);
            app.FiltremoyenneurSpinnerLabel.Text = 'Filtre moyenneur';
            app.choix_oversampling = uispinner(app.interface);
            app.choix_oversampling.Limits = [0 4];
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_test_cyclovolt2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interface)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interface)
        end
    end
end