classdef interface_test_diviseur_clk_precision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GnrerButton                matlab.ui.control.Button
        FrquenceCLKEditFieldLabel  matlab.ui.control.Label
        freq                       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: GnrerButton
        function generate(app, event)
            temps_attente = uint32(ceil(50000000/(2*app.freq.Value)))
            port = serial('COM4', 'BaudRate', 921600, 'DataTerminalReady', 'off', 'RequestToSend', 'off', 'Parity', 'None',...
                    'ByteOrder', 'bigEndian');
            fopen(port);
            fwrite(port, temps_attente, 'uint32');
            fclose(port);
            delete(port);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.GnrerButton = uibutton(app.UIFigure, 'push');
            app.GnrerButton.ButtonPushedFcn = createCallbackFcn(app, @generate, true);
            app.GnrerButton.Text = 'Générer';
            app.FrquenceCLKEditFieldLabel = uilabel(app.UIFigure);
            app.FrquenceCLKEditFieldLabel.Text = 'Fréquence CLK';
            app.freq = uieditfield(app.UIFigure, 'numeric');
            app.freq.Limits = [0.005 50000000];
            app.freq.Value = 1000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_test_diviseur_clk_precision()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end